classdef ClusterReviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLUSTERReviewerUIFigure  matlab.ui.Figure
        FileOptionsMenu          matlab.ui.container.Menu
        SetDirectory             matlab.ui.container.Menu
        AdvancedOptionsMenu      matlab.ui.container.Menu
        SetMinThresh             matlab.ui.container.Menu
        ProgramMenu              matlab.ui.container.Menu
        ExitProgramMenu          matlab.ui.container.Menu
        RestartProgramMenu       matlab.ui.container.Menu
        EPOCHViewerPanel         matlab.ui.container.Panel
        EpochDur                 matlab.ui.control.Spinner
        EpochDurSpinnerLabel     matlab.ui.control.Label
        EpochSpinner             matlab.ui.control.Spinner
        EpochSpinnerLabel        matlab.ui.control.Label
        forwardEpoch             matlab.ui.control.Button
        backwardEpoch            matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        AllWaveformsTab          matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        allCLUSTS                matlab.ui.control.UIAxes
        ClusteredWaveformsTab    matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        SC6                      matlab.ui.control.UIAxes
        SC5                      matlab.ui.control.UIAxes
        SC4                      matlab.ui.control.UIAxes
        SC3                      matlab.ui.control.UIAxes
        SC2                      matlab.ui.control.UIAxes
        SC1                      matlab.ui.control.UIAxes
        ClusteredOverlayTab      matlab.ui.container.Tab
        allCLUSTSover            matlab.ui.control.UIAxes
        ClusterMetricsTab        matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout5_2            matlab.ui.container.GridLayout
        Button4_2                matlab.ui.control.Button
        Button3_2                matlab.ui.control.Button
        Button2_2                matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        GridLayout5              matlab.ui.container.GridLayout
        Button4                  matlab.ui.control.Button
        Button3                  matlab.ui.control.Button
        Button2                  matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        allCLUSTSover_5          matlab.ui.control.UIAxes
        allCLUSTSover_7          matlab.ui.control.UIAxes
        allCLUSTSover_8          matlab.ui.control.UIAxes
        allCLUSTSover_3          matlab.ui.control.UIAxes
        ThresholdSettingsPanel   matlab.ui.container.Panel
        NegativeThresh           matlab.ui.control.StateButton
        TheshManVal              matlab.ui.control.NumericEditField
        ThreshSDSlider           matlab.ui.control.Slider
        ThreshSDSliderLabel      matlab.ui.control.Label
        ProgramInformationPanel  matlab.ui.container.Panel
        InfoLabel                matlab.ui.control.Label
        TimeSeriesDataPanel      matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        SpikeLocPlot             matlab.ui.control.UIAxes
        VoltRawPlot              matlab.ui.control.UIAxes
        ClusterOperationsPanel   matlab.ui.container.Panel
        ClusterRun               matlab.ui.control.Button
        ThresholdRun             matlab.ui.control.Button
        LoadCase                 matlab.ui.control.Button
        FileInformationPanel     matlab.ui.container.Panel
        FILESList                matlab.ui.control.ListBox
        FILESListBoxLabel        matlab.ui.control.Label
        ElecLabel                matlab.ui.control.Label
        FileNameLabel            matlab.ui.control.Label
        DepthnameLabel           matlab.ui.control.Label
    end

    % TO DO
    % 4/5/2023 - Create Timeseries viewer


    properties (Access = private)
        rawDatFold % Folder location where mat files with DLC data are stored
        spkCluFold % Folder location where spike times will be saved
        depFile2load % File name that will be loaded
        DepthID % Name of depth from file to be loaded
        allSPIKEdata % Spike data pulled from loaded file
        spikeDataRaw % CSPK raw voltage data
        spkFS % Spike sampling frequency
        startT % Spike recording time
        Elnum % electrode number
        waveForms % Waveform data
        minSPdist
        peakIndex % Index sample locations for threshold crossings
        XLIMa % X limit of waveform based on sample number
        fileNumber % Integer for the array ID for current file

        threshUsed % Threshold used for crossing
        kmeansNUsed % K number of clusters
        spkData % spike data used for clustering
        voltThresh % Voltage threshold
        minSepDist % minimum distance used between spikes
        cluMets % Cluster metrics

        numClusts % Number of clusters
        actClIDS % Active cluster ID
        clustID % Cluster ID
        features % Waveform features
        pnFlag2
        numEpochs % Number of Epochs
        EpochInds % Indicies of epoch starts and stops
        CurEpoch % Current epoch

    end
    
    methods (Access = private)
        
        function plotRastWaves(app, stage, sub)
            
            switch stage
                
                case 'new'
                    
                    % TIME
                    % timeDurSpk = round(length(app.spikeDataRaw)/app.spkFS,2);
                    % app.timeDur.Text = [num2str(timeDurSpk) , ' seconds'];
                    cla(app.allCLUSTS)
                    app.allCLUSTS.Title = [];
                    
                case 'threshold'
                    
                    cla(app.allCLUSTS)
                    
                    % TIME
                    % timeDurSpk = round(length(app.spikeDataRaw)/app.spkFS,2);
                    % app.timeDur.Text = [num2str(timeDurSpk) , ' seconds'];
                    app.allCLUSTS.Title = [];
                    
                    twaveForms = app.waveForms.allWaves.waves;
                    totLen = app.XLIMa;
                    
                    hold(app.allCLUSTS, 'on')
                    
                    if size(twaveForms,2) > 200
                        rwInd = randi(size(twaveForms,2),1,200);
                    else
                        rwInd = 1:size(twaveForms,2);
                    end
                    
                    twaves2plot = twaveForms(:,rwInd);

                    plot(app.allCLUSTS,twaves2plot,'Color',[72/255 202/255 202/255 0.25],'LineWidth',1)
   
                    xlim(app.allCLUSTS,[1 totLen]);
                    
                    if isempty(twaveForms)
                        ylim(app.allCLUSTS,[-1500 1500])
                    else
                        ylim(app.allCLUSTS,[min(twaves2plot(:)) max(twaves2plot(:))]);
                    end
                    
                    app.allCLUSTS.XTick = [];
                    app.allCLUSTS.YTick = [];
                    titleStr = ['All waveforms = ' , num2str(size(twaveForms,2))];
                    title(app.allCLUSTS,titleStr);
                    
                case '3'
                    
                    switch sub
                        case 'cluster'
                            
                            % Set up data to plot
                            tnumClusts = app.numClusts; % Single value with number of clusters
                            cluID = app.actClIDS; % Index vector (length of samples) with cluster IDs
                            cfilt = app.clustID; % Vector with actual cluster IDs
                            twaveForms = app.waveForms.allWaves.waves; % Waveforms
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Done!')
                            
                        case 'temp-sing'
                            
                            % Set up data to plot
                            tnumClusts = app.numClusts;
                            cluID = app.actClIDS;
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Templates Done!')
                            
                        case 'combine-clus'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Combined!')
                            
                        case 'delete-clus'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Deleted!')
                            
                        case 'temp-inc'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Template Inclusive!')
                            
                        case 'temp-exc'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Template Exclusive!')
                            
                        case 'clust-sing'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Re-Clustered!')
                            
                    end
                    
                    colors2 = [1 0 0 ;...
                        0 0 1 ;...
                        0 0.5 0 ;...
                        0.4941 0.1843 0.5569 ;...
                        0.8706 0.4902 0 ;...
                        1 0 1];
                    cla(app.meanClusts)
                    tyMax = 100;
                    tyMin = -100;
                    sdMAX = 100;
                    sdMIN = -100;
                    for ni = 1:tnumClusts
                        
                        cluN = cluID(ni);
                        
                        cla(app.(strcat('clust',num2str(ni))))
                        
                        c1Waves = twaveForms(:,cfilt == cluN);
                        
                        mW = mean(c1Waves,2);
                        sW = std(c1Waves,[],2);
                        uW = mW + (sW*2);
                        dW = mW - (sW*2);
                        
                        % Plot outliers
                        allc1Wvs = transpose(c1Waves);
                        
                        if size(allc1Wvs,1) < 30
                            toPlotOut = 1:size(allc1Wvs,1);
                        else
                            toPlotOut = randi(size(allc1Wvs,1),1,30);
                        end
                        
                        axes(app.(strcat('clust',num2str(ni))))
                        
                        hold on
                        plot(transpose(allc1Wvs(logical(toPlotOut),:)),'k','LineWidth',0.09)
                        
                        plot(mW,'Color',colors2(ni,:) ,'LineWidth',4)
                        plot(uW,'Color',colors2(ni,:) ,'LineStyle','--','LineWidth',2)
                        plot(dW,'Color',colors2(ni,:) ,'LineStyle','--','LineWidth',2)
                        
                        tWaveforms2 = twaveForms(:,cfilt == cluN);
                        
                        if isempty(tWaveforms2)
                            ylim([-300 300])
                        else
                            ylim([min(tWaveforms2(:)) max(tWaveforms2(:))]);
                        end
                        
                        % AXIS INFO FOR Individual cluster plots
                        xlim(app.(strcat('clust',num2str(ni))),[1 app.XLIMa]);
                        app.(strcat('clust',num2str(ni))).XTick = [];
                        app.(strcat('clust',num2str(ni))).YTick = [];
                        
                        numWaves = sum(cfilt == cluN);
                        
                        titleStr = ['Clust ', num2str(ni) , ' = '  , num2str(numWaves)];
                        title(app.(strcat('clust',num2str(ni))),titleStr,'Color',colors2(ni,:));
                        
                        axes(app.meanClusts)
                        hold on
                        plot(mean(twaveForms(:,cfilt == cluN),2),'Color',colors2(ni,:),'LineWidth',2.5)
                        % UPDATED min and max
                        aveWave = mean(twaveForms(:,cfilt == cluN),2);
                        sdWave = std(twaveForms(:,cfilt == cluN),[],2);
                        UPwave = aveWave + sdWave;
                        DNwave = aveWave - sdWave;
                        
                        baseX = 1:1:length(aveWave);
                        patX = [baseX fliplr(baseX)];
                        patY = [transpose(DNwave) fliplr(transpose(UPwave))];
                        
                        if min(aveWave) < tyMin
                            tyMin = min(aveWave);
                        end
                        
                        if max(aveWave) > tyMax
                            tyMax = max(aveWave);
                        end
                        
                        if min(patY) < sdMIN
                            sdMIN = min(patY);
                        end
                        
                        if max(patY) > sdMAX
                            sdMAX = max(patY);
                        end
                        
                        % AXIS INFO FOR Mean plot
                        ylim(app.meanClusts,[tyMin tyMax]);
                        xlim(app.meanClusts,[1 app.XLIMa]);
                        app.meanClusts.XTick = [];
                        app.meanClusts.YTick = [];
                        
                        cla(app.(strcat('sdC',num2str(ni))))
                        %                         axes(app.(strcat('sdC',num2str(ni))))
                        
                        patch(app.(strcat('sdC',num2str(ni))),patX,patY,colors2(ni,:),'EdgeColor','none','FaceAlpha',0.7);
                        
                        ylim(app.(strcat('sdC',num2str(ni))),[sdMIN sdMAX])
                        xlim(app.(strcat('sdC',num2str(ni))),[1 app.XLIMa])
                        app.(strcat('sdC',num2str(ni))).XTick = [];
                        app.(strcat('sdC',num2str(ni))).YTick = [];
                        
                    end
                    hold off
                    
                    
            end
        end
        
        function clearWavePlots(app)

            cla(app.allCLUSTSover)
            cla(app.allCLUSTS)
            for ci = 1:6
                tmpClustIa = ['SC' , num2str(ci)];
                cla(app.(tmpClustIa))
                app.(tmpClustIa).Title = [];
            end
            app.allCLUSTS.Title = [];
            app.allCLUSTSover.Title = [];

        end
        
        
        function messageUPDATE(app, coLOR, mesG)
            
            app.InfoLabel.Text = mesG;
            app.InfoLabel.FontColor = coLOR;
            
        end
        
        function updateThreshold(app , stage)

            sampData = app.spikeDataRaw;
            tspkData = double(sampData);
            sampLen = round(app.spkFS/1000);
            
            app.InfoLabel.Text = 'Estimating Threshold...';
            app.InfoLabel.FontColor = 'r';
            
            thresh = (round(std(double(sampData)) * app.TheshManVal.Value) + mean(double(sampData)));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% NEED to ADD slider for NOISE
            noise = (round(std(double(sampData)) * 20) + mean(double(sampData)));
            minDist = round(app.spkFS/1000) * app.minSPdist; % was 1.5
            
            tic;
            
            %%% Check threshold
            
            threshFlag = app.NegativeThresh.Value;
            if threshFlag == 0
                app.pnFlag2 = 1;
            else
                app.pnFlag2 = 0;
            end
            
            %%%%%%%%%%%%%%%%%%%%
            
            [ waveData ] = extractWaveforms_Clz_v06(tspkData, thresh, noise, minDist, app.spkFS, app.pnFlag2); %%%%%%%%%%%%%%%%%%%%% PNFLAG
            app.waveForms = waveData;
            
            app.InfoLabel.Text = 'Waves Extracted...';
            app.InfoLabel.FontColor = 'b';
            
            app.peakIndex = waveData.allWavesInfo.alllocs;
            
            totLen = length(1 - (sampLen/2):1 + (round(sampLen*0.8)-1));
            app.XLIMa = totLen;
            
            timeDone = toc;
            
            app.threshUsed = app.TheshManVal.Value;
            app.kmeansNUsed = 3;
            app.spkData = tspkData;
            app.voltThresh = thresh;
            app.minSepDist = minDist;
            
            plotRastWaves(app, 'threshold')
            
            app.InfoLabel.Text = ['Thresholding Done!' , '  time elapsed: ', num2str(round(timeDone)), ' s'] ;
            app.InfoLabel.FontColor = [0 0.4980 0];
            
            %%% Following Thresholding
            % 1. Make Clustering available
            % 2. Manual Thresholing available
            % 3. Abort analysis on bad cell
            
            % Initiate buttons
            app.ThresholdRun.Enable = 'off';
            app.ClusterRun.Enable = 'on';

            if stage == 1

                % Indicate threshold in window
                app.ThresholdSettingsPanel.Enable = 'on';
                app.TheshManVal.Value = 3;
                app.ThreshSDSlider.Value = app.TheshManVal.Value;
                app.ThreshSDSlider.Enable = 'on';

            end

            clearWavePlots(app);

            plotRastWaves(app, 'new')
            plotRastWaves(app, 'threshold')

            
        end
        
        function updateTimeSeriesPlot(app)

            % Need to output EPOCH, YLIM and XLIM values

            % Input app.CurEpoch, ylim , app.EpochInds
            % updateTimeSeriesPlot(app, ylim2use)

            meanYlim = mean(double(app.spikeDataRaw));
            stdYlim = std(double(app.spikeDataRaw));
            uppYlim = meanYlim + (stdYlim*7); % MAKE AN INPUT for STD
            dwnYlim = meanYlim - (stdYlim*7);

            ylim(app.VoltRawPlot,[dwnYlim uppYlim])

            spkDat2plot = app.spikeDataRaw(app.EpochInds(app.CurEpoch,1):...
                app.EpochInds(app.CurEpoch,2));
            colORvolt = [0.9412 0.9412 0.9412 0.8];

            plot(app.VoltRawPlot,spkDat2plot,'Color',colORvolt);

            % Set xlim for all
            epochLength = length(app.EpochInds(app.CurEpoch,1):app.EpochInds(app.CurEpoch,2));
            xlim(app.VoltRawPlot,[1 epochLength])

        end
        
        function updateWaveTSplot(app)

            epochID = app.CurEpoch;
            filrawDat = double(app.spikeDataRaw(app.EpochInds(epochID,1):app.EpochInds(epochID,2)));

            
            allWaveINDS = app.waveForms.allWavesInfo.alllocs;
            epochWaveInd = allWaveINDS >= app.EpochInds(epochID,1) & allWaveINDS <= app.EpochInds(epochID,2);
            epochWaveVales = find(epochWaveInd);

            allWAVES = app.waveForms.allWaves.waves;
            allPOSITIONS = app.waveForms.allWaves.positions;

            % Choose to which to plot pos or neg
            % Default to positive plot
            cla(app.SpikeLocPlot)
            xlim(app.SpikeLocPlot,[1 length(filrawDat)])
            hold(app.SpikeLocPlot,"on")
            for pNi = 1:length(epochWaveVales)

                tmpXwave = allPOSITIONS(:,epochWaveVales(pNi));
                plot(app.SpikeLocPlot,app.peakIndex(:,pNi),allWAVES(:,epochWaveVales(pNi)),...
                    "LineWidth",0.5,"Color",'r')
            end

            hold(app.SpikeLocPlot,"off")
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ThresholdSettingsPanel.Enable = 'off';
            app.minSPdist = 1.5;
            app.allCLUSTSover.Color = [0.2 0.2 0.2];
            app.allCLUSTS.Color = [0.2 0.2 0.2];
            app.VoltRawPlot.Color = [0.2 0.2 0.2];
            for ci = 1:6
                tmpClustIa = ['SC' , num2str(ci)];
                app.(tmpClustIa).Color = [0.2 0.2 0.2];
            end
        end

        % Button pushed function: LoadCase
        function LoadCasePushed(app, event)
            
            % ADD to FILE LIST - BUT LOCK down for now
            % CREATE timeseries plot - with EPOCH length ADD SPINNER to set
            % block duration 

            % CHECK HOW THIS WORKS! WITH COMPLETED FILES

            % CD to Raw data
            cd(app.rawDatFold)

            % Get list
            allLIST1 = dir('*.mat');
            allLIST2 = {allLIST1.name};
            app.FILESList.Items = allLIST2;

            fileLoadt = true;
            filCkCount = 1;
            while fileLoadt

                app.depFile2load = allLIST2{filCkCount};

                % Get case name
                firstPart = extractAfter(app.depFile2load,10);
                app.DepthID = extractBefore(firstPart,'F');

                % Look for field that indicates it has already been sorted
                tmpMatInfo = matfile(app.depFile2load );
                varlist = who(tmpMatInfo);

                if matches('sortedDone',varlist)

                    if filCkCount == length(varlist)
                        return
                    end

                    filCkCount = filCkCount + 1;

                else
                    app.depFile2load = allLIST2{filCkCount};
                    fileLoadt = false;
                end

            end

            app.fileNumber = find(matches(app.FILESList.Items,app.FILESList.Value));

            % Load file
            cd(app.rawDatFold)
            load(app.depFile2load);

            app.allSPIKEdata = dlcDepths.Spike;

            elecFields = fieldnames(app.allSPIKEdata);

            app.Elnum = elecFields{1}(2);
            tmpCspk = fieldnames(app.allSPIKEdata.(elecFields{1}).rawData);
            tmpHz = fieldnames(app.allSPIKEdata.(elecFields{1}).Hz);
            tmpSTt = fieldnames(app.allSPIKEdata.(elecFields{1}).startTime);

            app.spikeDataRaw = app.allSPIKEdata.(elecFields{1}).rawData.(tmpCspk{1});
            app.spkFS = (app.allSPIKEdata.(elecFields{1}).Hz.(tmpHz{1}))*1000;
            app.startT = app.allSPIKEdata.(elecFields{1}).startTime.(tmpSTt{1});

            % UpdateINFO
            app.FileNameLabel.Text = app.depFile2load;
            app.DepthnameLabel.Text = app.DepthID;
            app.ElecLabel.Text = elecFields{1};

            app.InfoLabel.Text = 'Loading files...';
            app.InfoLabel.FontColor = 'r';
            drawnow;

            app.InfoLabel.Text = 'File Loaded!!';
            app.InfoLabel.FontColor = [0 0.4980 0];
            drawnow;

            % Create timeseries and Epoch
            secEpoch = app.EpochDur.Value; % in seconds

            app.numEpochs = floor(width(app.spikeDataRaw)/(app.spkFS*secEpoch));
            app.EpochSpinner.Limits = [1 app.numEpochs];
            % app.maxEp.Value = app.numEpochs;  %%%%%%%%%%%%%%% CONSIDER ADDING
            startINDS = transpose(1:app.spkFS*secEpoch:(app.spkFS*app.numEpochs*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; width(app.spikeDataRaw)];
            app.EpochInds = [startINDS , stopINDS];
            app.CurEpoch = 1;

            % FUNCTION TO prep timeseries epoch
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            updateTimeSeriesPlot(app)

            plotRastWaves(app, 'new')

            % INITIATE buttons
            app.ThresholdRun.Enable = 'on';
            app.LoadCase.Enable = 'off';


        end

        % Button pushed function: ThresholdRun
        function ThresholdRunButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % SECOND STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THRESHOLD TRACE
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            updateThreshold(app , 1)

            updateWaveTSplot(app)

        end

        % Menu selected function: SetMinThresh
        function SetMinThreshMenuSelected(app, event)
            prompt = {'Min Distance btwn spikes (ms)'};
            dlg_title = 'Min Dist.';
            num_lines = [1 35];
            defaultans = {num2str(app.minSPdist)};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);

            app.minSPdist = str2double(answer{1});
        end

        % Menu selected function: ExitProgramMenu
        function ExitProgramMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: RestartProgramMenu
        function RestartProgramMenuSelected(app, event)
            delete(app)
            ClusterReviewer()
        end

        % Button pushed function: ClusterRun
        function ClusterRunButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THIRD STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % CLUSTER WAVEFORMS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % messageUPDATE(app, [1 0 0], 'Clustering...')
            
            twaveForms = app.waveForms.allWaves.waves;
            
            if size(twaveForms,2) >= 5
                
                [ xFeats ] = createSpkFeatures( twaveForms,  app.spkFS  );
                
                [idxF,Cen] = kmeans(xFeats, app.kmeansNUsed,'Distance','cityblock',...
                    'Replicates',6);
                
                [ nidx , app.cluMets , ~] = spkClusterQual( xFeats, idxF, Cen, 2 );
                
                tnumClusts = numel(unique(nidx));
                app.numClusts = tnumClusts;
                
                cluID = unique(nidx);
                app.actClIDS = cluID;
                
                cfilt = nidx;
                app.clustID = cfilt;
                app.features = xFeats;
            else
                app.numClusts = 1;
                app.actClIDS = 1;
                app.clustID = 1;
                app.features = nan;
            end
            
            cla(app.meanClusts);
            
            clearWavePlots(app)
            
            plotRastWaves(app, '3', 'cluster')
            
            messageUPDATE(app, [0 0.4980 0], 'Clusters Found!')
            
            %%% Following Thresholding
            % 1. Make Combine available
            % 2. Make Delete available
            % 3. Make Template available
            % 4. Close cluster
            
            % INITIATE BUTTONS
            
            app.expClus.Enable = 'on';
            app.threshold.Enable = 'off';
            app.clusterSpk.Enable = 'off';
            
            app.subTask.Enable = 'on';
        end

        % Value changed function: ThreshSDSlider
        function ThreshSDSliderValueChanged(app, event)
            
            app.ClusterRun.Enable = 'on';
            %%%%%%%%%%%% TURN OFF EXPORT CLUSTER BUTTON
            % app.expClus.Enable = 'off';
            app.TheshManVal.Value = app.ThreshSDSlider.Value;

            updateThreshold(app , 2)
            
        end

        % Value changed function: NegativeThresh
        function NegativeThreshValueChanged(app, event)
            
            updateThreshold(app , 2)
            
        end

        % Menu selected function: SetDirectory
        function SetDirectoryMenuSelected(app, event)
            tmpRAWdata = uigetdir();

            app.rawDatFold =  tmpRAWdata;
            wildPAT = wildcardPattern("Except","\");
            tmppat = "\" + wildPAT + textBoundary;
            mainPath = extractBefore(app.rawDatFold,tmppat);
            app.spkCluFold =  [mainPath , filesep , 'ClusteredSpikeTimes', filesep];

            if ~exist(app.spkCluFold,'dir')
                mkdir(app.spkCluFold)
            end

            app.LoadCase.Enable = 'on';
        end

        % Button pushed function: backwardEpoch
        function backwardEpochButtonPushed(app, event)
            % Do something if hit end
            if app.CurEpoch == 1
                app.EpochSpinner.Value = 1;
                return
            else
                app.CurEpoch = app.CurEpoch - 1;
                app.EpochSpinner.Value = app.CurEpoch;
            end

            updateTimeSeriesPlot(app)

            % updateNLXPLOT(app)
            % Calculate waveform features for first block
            % calculateWaves(app)
            % Plot waveforms
            % updateWAVEplot(app)
        end

        % Button pushed function: forwardEpoch
        function forwardEpochButtonPushed(app, event)
            % Update epoch spinner

            % Do something if hit end
            if app.CurEpoch == app.numEpochs
                app.EpochSpinner.Value = app.numEpochs;
                return
            else
                app.CurEpoch = app.CurEpoch + 1;
                app.EpochSpinner.Value = app.CurEpoch;
            end

            updateTimeSeriesPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CLUSTERReviewerUIFigure = uifigure('Visible', 'off');
            app.CLUSTERReviewerUIFigure.Name = 'CLUSTER Reviewer';
            app.CLUSTERReviewerUIFigure.Icon = fullfile(pathToMLAPP, 'appImage.jpg');
            app.FileOptionsMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.FileOptionsMenu.Text = 'File Options';
            app.SetDirectory = uimenu(app.FileOptionsMenu);
            app.SetDirectory.MenuSelectedFcn = createCallbackFcn(app, @SetDirectoryMenuSelected, true);
            app.SetDirectory.Accelerator = 'D';
            app.SetDirectory.Text = 'Set &Directory';
            app.AdvancedOptionsMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.AdvancedOptionsMenu.Text = 'Advanced Options';
            app.SetMinThresh = uimenu(app.AdvancedOptionsMenu);
            app.SetMinThresh.MenuSelectedFcn = createCallbackFcn(app, @SetMinThreshMenuSelected, true);
            app.SetMinThresh.Text = 'Minimum Distance btw spikes';
            app.ProgramMenu = uimenu(app.CLUSTERReviewerUIFigure);
            app.ProgramMenu.Text = 'Program';
            app.ExitProgramMenu = uimenu(app.ProgramMenu);
            app.ExitProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgramMenuSelected, true);
            app.ExitProgramMenu.Text = 'Exit Program';
            app.RestartProgramMenu = uimenu(app.ProgramMenu);
            app.RestartProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartProgramMenuSelected, true);
            app.RestartProgramMenu.Text = 'Restart Program';
            app.ClusterOperationsPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ClusterOperationsPanel.Title = 'Cluster Operations';
            app.FileInformationPanel = uipanel(app.ClusterOperationsPanel);
            app.FileInformationPanel.Title = 'File Information';
            app.DepthnameLabel = uilabel(app.FileInformationPanel);
            app.DepthnameLabel.Text = 'Depth name';
            app.FileNameLabel = uilabel(app.FileInformationPanel);
            app.FileNameLabel.Text = 'File Name';
            app.ElecLabel = uilabel(app.FileInformationPanel);
            app.ElecLabel.Text = 'Elec. #';
            app.FILESListBoxLabel = uilabel(app.FileInformationPanel);
            app.FILESListBoxLabel.Text = 'FILES';
            app.FILESList = uilistbox(app.FileInformationPanel);
            app.LoadCase = uibutton(app.ClusterOperationsPanel, 'push');
            app.LoadCase.ButtonPushedFcn = createCallbackFcn(app, @LoadCasePushed, true);
            app.LoadCase.Icon = fullfile(pathToMLAPP, 'loadButton2.jpg');
            app.LoadCase.Enable = 'off';
            app.LoadCase.Tooltip = {'Load button - load a mat file'};
            app.LoadCase.Text = '';
            app.ThresholdRun = uibutton(app.ClusterOperationsPanel, 'push');
            app.ThresholdRun.ButtonPushedFcn = createCallbackFcn(app, @ThresholdRunButtonPushed, true);
            app.ThresholdRun.Icon = fullfile(pathToMLAPP, 'threshButton.jpg');
            app.ThresholdRun.Enable = 'off';
            app.ThresholdRun.Tooltip = {'Threshold button will use threshold to isolate spikes'};
            app.ThresholdRun.Text = '';
            app.ClusterRun = uibutton(app.ClusterOperationsPanel, 'push');
            app.ClusterRun.ButtonPushedFcn = createCallbackFcn(app, @ClusterRunButtonPushed, true);
            app.ClusterRun.Icon = fullfile(pathToMLAPP, 'clusterButton.jpg');
            app.ClusterRun.Enable = 'off';
            app.ClusterRun.Tooltip = {'Run cluster process'};
            app.ClusterRun.Text = '';
            app.TimeSeriesDataPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.TimeSeriesDataPanel.Title = 'Time Series Data';
            app.GridLayout = uigridlayout(app.TimeSeriesDataPanel);
            app.VoltRawPlot = uiaxes(app.GridLayout);
            subtitle(app.VoltRawPlot, 'Filtered Data')
            app.VoltRawPlot.XTick = [];
            app.VoltRawPlot.YTick = [];
            app.SpikeLocPlot = uiaxes(app.GridLayout);
            subtitle(app.SpikeLocPlot, 'Spike locations')
            app.SpikeLocPlot.XTick = [];
            app.SpikeLocPlot.YTick = [];
            app.ProgramInformationPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ProgramInformationPanel.Title = 'Program Information';
            app.InfoLabel = uilabel(app.ProgramInformationPanel);
            app.InfoLabel.Text = 'Progress.....';
            app.ThresholdSettingsPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.ThresholdSettingsPanel.Title = 'Threshold Settings';
            app.ThreshSDSliderLabel = uilabel(app.ThresholdSettingsPanel);
            app.ThreshSDSliderLabel.Text = 'Thresh. SD';
            app.ThreshSDSlider = uislider(app.ThresholdSettingsPanel);
            app.ThreshSDSlider.Limits = [1 6];
            app.ThreshSDSlider.MajorTicks = [];
            app.ThreshSDSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSDSliderValueChanged, true);
            app.ThreshSDSlider.MinorTicks = [];
            app.ThreshSDSlider.Value = 4;
            app.TheshManVal = uieditfield(app.ThresholdSettingsPanel, 'numeric');
            app.TheshManVal.Limits = [1 6];
            app.TheshManVal.Editable = 'off';
            app.TheshManVal.Value = 4;
            app.NegativeThresh = uibutton(app.ThresholdSettingsPanel, 'state');
            app.NegativeThresh.ValueChangedFcn = createCallbackFcn(app, @NegativeThreshValueChanged, true);
            app.NegativeThresh.Text = 'Switch to Negative';
            app.TabGroup = uitabgroup(app.CLUSTERReviewerUIFigure);
            app.AllWaveformsTab = uitab(app.TabGroup);
            app.AllWaveformsTab.Title = 'All Waveforms';
            app.GridLayout3 = uigridlayout(app.AllWaveformsTab);
            app.allCLUSTS = uiaxes(app.GridLayout3);
            app.allCLUSTS.XTick = [];
            app.allCLUSTS.YTick = [];
            app.ClusteredWaveformsTab = uitab(app.TabGroup);
            app.ClusteredWaveformsTab.Title = 'Clustered Waveforms';
            app.GridLayout2 = uigridlayout(app.ClusteredWaveformsTab);
            app.SC1 = uiaxes(app.GridLayout2);
            app.SC1.XTick = [];
            app.SC1.YTick = [];
            app.SC2 = uiaxes(app.GridLayout2);
            app.SC2.XTick = [];
            app.SC2.YTick = [];
            app.SC3 = uiaxes(app.GridLayout2);
            app.SC3.XTick = [];
            app.SC3.YTick = [];
            app.SC4 = uiaxes(app.GridLayout2);
            app.SC4.XTick = [];
            app.SC4.YTick = [];
            app.SC5 = uiaxes(app.GridLayout2);
            app.SC5.XTick = [];
            app.SC5.YTick = [];
            app.SC6 = uiaxes(app.GridLayout2);
            app.SC6.XTick = [];
            app.SC6.YTick = [];
            app.ClusteredOverlayTab = uitab(app.TabGroup);
            app.ClusteredOverlayTab.Title = 'Clustered Overlay';
            app.allCLUSTSover = uiaxes(app.ClusteredOverlayTab);
            app.allCLUSTSover.XTick = [];
            app.allCLUSTSover.YTick = [];
            app.ClusterMetricsTab = uitab(app.TabGroup);
            app.ClusterMetricsTab.Title = 'Cluster Metrics';
            app.GridLayout4 = uigridlayout(app.ClusterMetricsTab);
            app.allCLUSTSover_3 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_3.XTick = [];
            app.allCLUSTSover_3.YTick = [];
            app.allCLUSTSover_8 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_8.XTick = [];
            app.allCLUSTSover_8.YTick = [];
            app.allCLUSTSover_7 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_7.XTick = [];
            app.allCLUSTSover_7.YTick = [];
            app.allCLUSTSover_5 = uiaxes(app.GridLayout4);
            app.allCLUSTSover_5.XTick = [];
            app.allCLUSTSover_5.YTick = [];
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button2 = uibutton(app.GridLayout5, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GridLayout5, 'push');
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.GridLayout5, 'push');
            app.Button4.Text = 'Button4';
            app.GridLayout5_2 = uigridlayout(app.GridLayout4);
            app.Button_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button2_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button3_2.Text = 'Button3';
            app.Button4_2 = uibutton(app.GridLayout5_2, 'push');
            app.Button4_2.Text = 'Button4';
            app.EPOCHViewerPanel = uipanel(app.CLUSTERReviewerUIFigure);
            app.EPOCHViewerPanel.Title = 'EPOCH Viewer';
            app.backwardEpoch = uibutton(app.EPOCHViewerPanel, 'push');
            app.backwardEpoch.ButtonPushedFcn = createCallbackFcn(app, @backwardEpochButtonPushed, true);
            app.backwardEpoch.Icon = fullfile(pathToMLAPP, 'backButton.jpg');
            app.backwardEpoch.Text = '';
            app.forwardEpoch = uibutton(app.EPOCHViewerPanel, 'push');
            app.forwardEpoch.ButtonPushedFcn = createCallbackFcn(app, @forwardEpochButtonPushed, true);
            app.forwardEpoch.Icon = fullfile(pathToMLAPP, 'forwardButton.jpg');
            app.forwardEpoch.Text = '';
            app.EpochSpinnerLabel = uilabel(app.EPOCHViewerPanel);
            app.EpochSpinnerLabel.Text = 'Epoch#';
            app.EpochSpinner = uispinner(app.EPOCHViewerPanel);
            app.EpochDurSpinnerLabel = uilabel(app.EPOCHViewerPanel);
            app.EpochDurSpinnerLabel.Text = 'Epoch Dur';
            app.EpochDur = uispinner(app.EPOCHViewerPanel);
            app.EpochDur.Step = 5;
            app.EpochDur.Limits = [5 20];
            app.EpochDur.Value = 5;
            % Show the figure after all components are created
            app.CLUSTERReviewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClusterReviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLUSTERReviewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLUSTERReviewerUIFigure)
        end
    end
end