classdef OsortOutputViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FolderLoadButton      matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        f5                    matlab.ui.control.UIAxes
        f4                    matlab.ui.control.UIAxes
        f3                    matlab.ui.control.UIAxes
        f2                    matlab.ui.control.UIAxes
        f1                    matlab.ui.control.UIAxes
        MergeDeletePanel      matlab.ui.container.Panel
        WIRELabel             matlab.ui.control.Label
        DELETEButton          matlab.ui.control.Button
        MERGEButton           matlab.ui.control.Button
        ReleaseButton         matlab.ui.control.StateButton
        ListBox               matlab.ui.control.ListBox
        clustALL              matlab.ui.control.DropDown
        ClusterDropDownLabel  matlab.ui.control.Label
        wireALL               matlab.ui.control.DropDown
        WIREDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainDIR % Description
        wireLIST % Description
        allwaves % Description
        allclustIDs % Description
        allcrossIDs % Description
        alltime % Description
        spkScaleFactor
        plotINFO
    end
    
    methods (Access = private)
        
        function updateWirelist(app)

            curWire = app.wireALL.Value;
            load(curWire,'useNegative','newSpikesNegative','assignedNegative',...
                'newTimestampsNegative','scalingFactor','allcluststr');
            app.allclustIDs = useNegative;
            app.allwaves = newSpikesNegative;
            app.allcrossIDs = assignedNegative;
            app.alltime = newTimestampsNegative;
            app.clustALL.Items = cellstr(num2str(app.allclustIDs));
            app.spkScaleFactor = scalingFactor;
            app.plotINFO = allcluststr;

            updateClustplots(app)
           
        end
        
        function updateClustplots(app)

            curCLUST = str2double(app.clustALL.Value);

            cluFields = fieldnames(app.plotINFO);

            for ci = 1:length(cluFields)

                if app.plotINFO.(cluFields{ci}).ID == curCLUST
                    clu2use = cluFields{ci};
                end

            end

            clu2plot = app.plotINFO.(clu2use);

            % Figure 1
            A = clu2plot.F1.spikesToDraw(:,1:clu2plot.F1.spikeLength);
            winP = 4;
            bins = 1000;
            times = [];
            margins = round(bins.*0.10);%10% margins;
            win = bins.*winP./100;

            added = min(A(:));
            A = A-added;
            res = (max(A(:))./bins);

            A = round(A./res)+margins;
            range=max(A(:))+margins;

            AMPs = ((1:range)-margins).*(res)+added;

            for t=1:size(A,2)
                clear C
                C(range,size(A,1))=0;

                K=sub2ind(size(C),A(:,t)',1:size(A,1));
                C(K)=1;
                C=mean(C,2);

                D(:,t) = conv(C, gausswin(win)/sum(gausswin(win)),'same');
            end

            imagesc(app.f1,times,AMPs,D);
            set(app.f1,'Colormap',clu2plot.F1.colorMAP);
            xlim(app.f1,[1 256])
            title(app.f1, 'probability density')

            % Figure 2
            plot(app.f2, 1:clu2plot.F2.spikeLength, (clu2plot.F2.spikesToDraw')*-1,...
                'Color', clu2plot.spkColor/255);
            hold(app.f2, 'on')
            avColor ='k';
            % plot waveform
            plot(app.f2, 1:clu2plot.F2.spikeLength, (mean(clu2plot.F2.spikesToDraw))*-1,...
                avColor, 'linewidth', 2);
            hold(app.f2, 'off')
            xlim(app.f2,[1 256])
            title(app.f2, ['Spike# = ' num2str(size(clu2plot.F2.spikesToDraw,1))])

            % Figure 3
            plot(app.f3, clu2plot.F3.tvect,clu2plot.F3.Cxx,'r','LineWidth',2);
            title(app.f3,'Autocorrelation (>10ms)');
            ylabel(app.f3,'(spk/s)^2/Hz');
            xlabel(app.f3,'[ms]');
            xlim(app.f3,[0 20]);

            % Figure 4
            bar(app.f4, clu2plot.F4.edges1, clu2plot.F4.n1,'histc');
            title(app.f4, sprintf('ISI bin=1ms, mean=%.1f, below 3ms=%.2f%%',...
                clu2plot.F4.m1,clu2plot.F4.percbelow(1),clu2plot.F4.percbelow(3)));
            ylabel(app.f4, ['CV =' num2str(clu2plot.F4.CV,3)] );
            hold(app.f4, 'on')
            plot(app.f4,clu2plot.F4.edges1, clu2plot.F4.yGam1, 'r','linewidth',2);

            % Figure 5
            allTS = clu2plot.F5.allTimestamps;

            yyaxis(app.f5, 'left')
            plot(app.f5, linspace(allTS(1),allTS(end),100)/1000000-allTS(1)/1e6,...
                clu2plot.F5.ampYax, 'LineWidth',2,'LineStyle','--')
            ylabel(app.f5,'spike amplitude (mV)')

            yyaxis(app.f5, 'right')
            plot(app.f5, linspace(allTS(1),allTS(end),100)/1000000-allTS(1)/1e6,...
                clu2plot.F5.firrateYax, 'LineWidth',2)
            ylabel(app.f5,'spike rate during analysis')

            try
                ylim(app.f5,[min(clu2plot.F5.ampYax) max(clu2plot.F5.ampYax)]);
            catch
                ylim(app.f5,[min(clu2plot.F5.ampYax)-1 max(clu2plot.F5.ampYax)]);
            end

            xlabel(app.f5, 'time (sec)');
            title(app.f5,'spike rate & amplitude during analyzed period');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FolderLoadButton
        function FolderLoadButtonPushed(app, event)
            

            app.mainDIR = uigetdir();
            cd(app.mainDIR);
            % Get list of wires
            matDIR1 = dir('*.mat');
            matDIR2 = {matDIR1.name};
            app.wireLIST = matDIR2;
            app.wireALL.Items = app.wireLIST;

            updateWirelist(app)


        end

        % Value changed function: clustALL
        function clustALLValueChanged(app, event)
            updateClustplots(app)
            
        end

        % Value changed function: wireALL
        function wireALLValueChanged(app, event)
            updateWirelist(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WIREDropDownLabel = uilabel(app.UIFigure);
            app.WIREDropDownLabel.Text = 'WIRE';
            app.wireALL = uidropdown(app.UIFigure);
            app.wireALL.ValueChangedFcn = createCallbackFcn(app, @wireALLValueChanged, true);
            app.ClusterDropDownLabel = uilabel(app.UIFigure);
            app.ClusterDropDownLabel.Text = 'Cluster';
            app.clustALL = uidropdown(app.UIFigure);
            app.clustALL.ValueChangedFcn = createCallbackFcn(app, @clustALLValueChanged, true);
            app.MergeDeletePanel = uipanel(app.UIFigure);
            app.MergeDeletePanel.Title = 'Merge / Delete';
            app.ListBox = uilistbox(app.MergeDeletePanel);
            app.ReleaseButton = uibutton(app.MergeDeletePanel, 'state');
            app.ReleaseButton.Text = 'Release';
            app.MERGEButton = uibutton(app.MergeDeletePanel, 'push');
            app.MERGEButton.Text = 'MERGE';
            app.DELETEButton = uibutton(app.MergeDeletePanel, 'push');
            app.DELETEButton.Text = 'DELETE';
            app.WIRELabel = uilabel(app.MergeDeletePanel);
            app.WIRELabel.Text = 'WIRE #';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.f1 = uiaxes(app.GridLayout);
            title(app.f1, 'Title')
            app.f1.XTick = [];
            app.f1.YTick = [];
            app.f2 = uiaxes(app.GridLayout);
            title(app.f2, 'Title')
            app.f2.XTick = [];
            app.f2.YTick = [];
            app.f3 = uiaxes(app.GridLayout);
            title(app.f3, 'Title')
            app.f3.XTick = [];
            app.f3.YTick = [];
            app.f4 = uiaxes(app.GridLayout);
            title(app.f4, 'Title')
            app.f4.XTick = [];
            app.f4.YTick = [];
            app.f5 = uiaxes(app.GridLayout);
            title(app.f5, 'Title')
            app.f5.XTick = [];
            app.f5.YTick = [];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Wire'; 'Clust'; 'Task'};
            app.UITable.RowName = {};
            app.FolderLoadButton = uibutton(app.UIFigure, 'push');
            app.FolderLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FolderLoadButtonPushed, true);
            app.FolderLoadButton.Text = 'Folder Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OsortOutputViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end