classdef OsortOutputViewer_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadMenu              matlab.ui.container.Menu
        ExportMenu            matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        outTable              matlab.ui.control.Table
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        f6                    matlab.ui.control.UIAxes
        f5                    matlab.ui.control.UIAxes
        f4                    matlab.ui.control.UIAxes
        f3                    matlab.ui.control.UIAxes
        f2                    matlab.ui.control.UIAxes
        f1                    matlab.ui.control.UIAxes
        MergeDeletePanel      matlab.ui.container.Panel
        WIRELabel             matlab.ui.control.Label
        DELETEButton          matlab.ui.control.Button
        MERGEButton           matlab.ui.control.Button
        ReleaseButton         matlab.ui.control.StateButton
        clusterLIST           matlab.ui.control.ListBox
        clustALL              matlab.ui.control.DropDown
        ClusterDropDownLabel  matlab.ui.control.Label
        wireALL               matlab.ui.control.DropDown
        WIREDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainDIR % Description
        wireLIST % Description
        allwaves % Description
        allclustIDs % Description
        allcrossIDs % Description
        alltime % Description
        spkScaleFactor
        plotINFO
    end
    
    methods (Access = private)
        
        function updateWirelist(app)

            curWire = app.wireALL.Value;
            load(curWire,'useNegative','newSpikesNegative','assignedNegative',...
                'newTimestampsNegative','scalingFactor','allcluststr');
            app.allclustIDs = useNegative;
            app.allwaves = newSpikesNegative;
            app.allcrossIDs = assignedNegative;
            app.alltime = newTimestampsNegative;
            app.clustALL.Items = cellstr(num2str(app.allclustIDs));
            app.spkScaleFactor = scalingFactor;
            app.plotINFO = allcluststr;

            app.clusterLIST.Items = cellstr(num2str(app.allclustIDs));

            updateClustplots(app)
           
        end
        
        function updateClustplots(app)

            curCLUST = str2double(app.clustALL.Value);

            cluFields = fieldnames(app.plotINFO);

            for ci = 1:length(cluFields)

                if app.plotINFO.(cluFields{ci}).ID == curCLUST
                    clu2use = cluFields{ci};
                end

            end

            clu2plot = app.plotINFO.(clu2use);

            % Figure 2 - % GET FRACTION
            plot(app.f1, 1:clu2plot.F2.spikeLength, (clu2plot.F2.spikesToDraw')*-1,...
                'Color', clu2plot.spkColor/255);
            hold(app.f1, 'on')
            avColor ='k';
            % plot waveform
            plot(app.f1, 1:clu2plot.F2.spikeLength, (mean(clu2plot.F2.spikesToDraw))*-1,...
                avColor, 'linewidth', 2);
            hold(app.f1, 'off')
            xlim(app.f1,[1 256])
            title(app.f1, ['Spike# = ' num2str(size(clu2plot.F2.spikesToDraw,1))])

            % Figure 3
            plot(app.f2, clu2plot.F3.tvect,clu2plot.F3.Cxx,'r','LineWidth',2);
            title(app.f2,'Autocorrelation (>10ms)');
            ylabel(app.f2,'(spk/s)^2/Hz');
            xlabel(app.f2,'[ms]');
            xlim(app.f2,[0 20]);
            xticks(app.f2,[1 5 10 20])

            % Figure 4
            bar(app.f3, clu2plot.F4.edges1, clu2plot.F4.n1,'histc');
            title(app.f3, sprintf('ISI bin=1ms, mean=%.1f',...
                clu2plot.F4.m1));
            ylabel(app.f3, ['CV =' num2str(clu2plot.F4.CV,3)] );
            hold(app.f3, 'on')
            plot(app.f3,clu2plot.F4.edges1, clu2plot.F4.yGam1, 'r','linewidth',2);
            xticks(app.f3,round(linspace(0,20,10)))
            xlim(app.f3,[0 20])
            xline(app.f3,1)
            xlabel(app.f3,'ms')
            hold(app.f3, 'off')

            % Figure 5
            allTS = clu2plot.F5.allTimestamps;

            yyaxis(app.f4, 'right')
            plot(app.f4, linspace(allTS(1),allTS(end),100)/1000000-allTS(1)/1e6,...
                clu2plot.F5.firrateYax, 'LineWidth',2)
            ylabel(app.f4,'spike rate (Hz)')

            yyaxis(app.f4, 'left')
            plot(app.f4, linspace(allTS(1),allTS(end),100)/1000000-allTS(1)/1e6,...
                clu2plot.F5.ampYax, 'LineWidth',2,'LineStyle','--')
            ylabel(app.f4,'spike amplitude (mV)')

            xlabel(app.f4, 'time (sec)');
            title(app.f4,'spike rate & amplitude');

            % Two all cluster plots
            clusWall = fieldnames(app.plotINFO);
            title(app.f5,'Average waveforms')
            title(app.f6,'Overview of spike fidelity')

            clusterIDS = cell(1,length(clusWall));
            for fi = 1:2
                for ci2 = 1:length(clusWall)

                    clu2tmp = app.plotINFO.(clusWall{ci2});
                    clusterIDS{ci2} = num2str(clu2tmp.ID);

                    switch fi
                        case 1
                            plot(app.f5, 1:clu2tmp.F2.spikeLength, (mean(clu2tmp.F2.spikesToDraw))*-1,...
                                'Color',clu2tmp.spkColor/255, 'linewidth', 2);
                            hold(app.f5, 'on')
                            xlim(app.f5,[1 256])

                        case 2
                            hold(app.f5, 'off')
                            allTS = clu2tmp.F5.allTimestamps;
                            plot(app.f6, linspace(allTS(1),allTS(end),100)/1000000-allTS(1)/1e6,...
                                clu2tmp.F5.firrateYax, 'Color', clu2tmp.spkColor/255,'LineWidth',2)
                            ylabel(app.f6,'spike rate (Hz)')
                            hold(app.f6, 'on')
                    end
                end
            end
            legend(app.f5,clusterIDS)
            legend(app.f6,clusterIDS)
            hold(app.f6, 'off')

            
        end
        
        function updateTABLE(app, step)

            switch step
                case 1
                    % Loop through wire files
                    wireNUMs = {};
                    clustNUMs = [];
                    wireIDS = app.wireLIST;
                    clustCnt = 1;
                    for wi = 1:length(wireIDS)
                        wireNUMt = extractBetween(wireIDS{wi},'A','_');
                        load(wireIDS{wi},'allcluststr');
                        clusWall = fieldnames(allcluststr);
                        % Load in cluster names per wire
                        for ci = 1:length(clusWall)
                            clustIDt = allcluststr.(clusWall{ci}).ID;
                            clustNUMs(clustCnt,1) = clustIDt; %#ok<*AGROW> 
                            wireNUMs{clustCnt,1} = wireNUMt{1};
                            clustCnt = clustCnt + 1;
                        end
                    end
                    outcome = repmat({'Keep'},height(clustNUMs),1);
                    startTable = table(wireNUMs,clustNUMs,outcome);
                    app.outTable.Data = startTable;
                case 2


            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: clustALL
        function clustALLValueChanged(app, event)
            updateClustplots(app)
            
        end

        % Value changed function: wireALL
        function wireALLValueChanged(app, event)
            updateWirelist(app)
            
        end

        % Value changed function: ReleaseButton
        function ReleaseButtonValueChanged(app, event)
            value = app.ReleaseButton.Value;

            switch value
                case 0
                    % turn off stuff
                    app.DELETEButton.Enable = 'off';
                    app.MERGEButton.Enable = 'off';
                    app.clusterLIST.Enable = 'off';

                case 1
                    app.DELETEButton.Enable = 'on';
                    app.MERGEButton.Enable = 'on';
                    app.clusterLIST.Enable = 'on';

            end
            
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            
            % Identify wire
            curWire = app.wireALL.Value;
            curWireNm = extractBetween(curWire,'A','_');

            % Identify cluster ids
            clusts2del = str2double(app.clusterLIST.Value);
            
            % Change in Table
            tmpTab = app.outTable.Data;
            tabRows = matches(tmpTab.wireNUMs,curWireNm) &...
                      ismember(tmpTab.clustNUMs,clusts2del);
            app.outTable.Data.outcome(tabRows) = {'Delete'};


        end

        % Button pushed function: MERGEButton
        function MERGEButtonPushed(app, event)

            % Identify wire
            curWire = app.wireALL.Value;
            curWireNm = extractBetween(curWire,'A','_');

            % Identify cluster ids
            clusts2mer = str2double(app.clusterLIST.Value);
            clusts2mer2 = cellfun(@(x) num2str(x) , num2cell(clusts2mer) , 'UniformOutput' , false);
            clusts2mer2p = strjoin(clusts2mer2,',');

            % Change in Table
            tmpTab = app.outTable.Data;
            tabRows = matches(tmpTab.wireNUMs,curWireNm) &...
                ismember(tmpTab.clustNUMs,clusts2mer);

            mergeMessage = ['Merge [', clusts2mer2p, ']'];

            app.outTable.Data.outcome(tabRows) = {mergeMessage};


        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
            app.mainDIR = uigetdir();
            cd(app.mainDIR);
            % Get list of wires
            matDIR1 = dir('*.mat');
            matDIR2 = {matDIR1.name};
            app.wireLIST = matDIR2;
            app.wireALL.Items = app.wireLIST;

            updateTABLE(app , 1)

            updateWirelist(app)

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            
            % Loop through table and save final structure per wire
            % 1. Save time index
            % 2. Save waveform
            % 3. New cluster ID 
            % 4. Processing information
            for wi = length(app.wireALL.Items)
                curWire = app.wireALL.Items{wi};
                % Ensure directory location % FUTURE
                curWireNm = extractBetween(curWire,'A','_');
                % Locate in Table
                tmpTab = app.outTable.Data;
                tabRows = matches(tmpTab.wireNUMs,curWireNm);
                tmpWrows = tmpTab(tabRows,:);

                for ti = 1:height(tmpWrows)
                    % Get cluster id
                    % Do stuff to cluster - merge, delete, or leave alone
                    % Remap
                    % Save time index
                    % Save waveform
                    % Save info


                end
                % Append finalSpk struct to file
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.SaveMenu = uimenu(app.ExportMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.WIREDropDownLabel = uilabel(app.UIFigure);
            app.WIREDropDownLabel.Text = 'WIRE';
            app.wireALL = uidropdown(app.UIFigure);
            app.wireALL.ValueChangedFcn = createCallbackFcn(app, @wireALLValueChanged, true);
            app.ClusterDropDownLabel = uilabel(app.UIFigure);
            app.ClusterDropDownLabel.Text = 'Cluster';
            app.clustALL = uidropdown(app.UIFigure);
            app.clustALL.ValueChangedFcn = createCallbackFcn(app, @clustALLValueChanged, true);
            app.MergeDeletePanel = uipanel(app.UIFigure);
            app.MergeDeletePanel.Title = 'Merge / Delete';
            app.clusterLIST = uilistbox(app.MergeDeletePanel);
            app.clusterLIST.Multiselect = 'on';
            app.clusterLIST.Enable = 'off';
            app.clusterLIST.Value = {'Item 1'};
            app.ReleaseButton = uibutton(app.MergeDeletePanel, 'state');
            app.ReleaseButton.ValueChangedFcn = createCallbackFcn(app, @ReleaseButtonValueChanged, true);
            app.ReleaseButton.Text = 'Release';
            app.MERGEButton = uibutton(app.MergeDeletePanel, 'push');
            app.MERGEButton.ButtonPushedFcn = createCallbackFcn(app, @MERGEButtonPushed, true);
            app.MERGEButton.Enable = 'off';
            app.MERGEButton.Text = 'MERGE';
            app.DELETEButton = uibutton(app.MergeDeletePanel, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Enable = 'off';
            app.DELETEButton.Text = 'DELETE';
            app.WIRELabel = uilabel(app.MergeDeletePanel);
            app.WIRELabel.Text = 'WIRE #';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.f1 = uiaxes(app.GridLayout);
            title(app.f1, 'Title')
            app.f1.XTick = [];
            app.f1.YTick = [];
            app.f2 = uiaxes(app.GridLayout);
            title(app.f2, 'Title')
            app.f2.XTick = [];
            app.f2.YTick = [];
            app.f3 = uiaxes(app.GridLayout);
            title(app.f3, 'Title')
            app.f3.XTick = [];
            app.f3.YTick = [];
            app.f4 = uiaxes(app.GridLayout);
            title(app.f4, 'Title')
            app.f4.XTick = [];
            app.f4.YTick = [];
            app.f5 = uiaxes(app.GridLayout);
            title(app.f5, 'Title')
            app.f5.XTick = [];
            app.f5.YTick = [];
            app.f6 = uiaxes(app.GridLayout);
            title(app.f6, 'Title')
            app.f6.XTick = [];
            app.f6.YTick = [];
            app.outTable = uitable(app.UIFigure);
            app.outTable.ColumnName = {'Wire'; 'Clust'; 'Task'};
            app.outTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OsortOutputViewer_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end