classdef OsortSpikeAssess_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ClusterProcess      matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        FUTUREButton_2      matlab.ui.control.Button
        DELETEButton        matlab.ui.control.Button
        COMBINEButton       matlab.ui.control.Button
        SpikelocationPanel  matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        timeSeries          matlab.ui.control.UIAxes
        ClusterSelect       matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        C20B                matlab.ui.control.StateButton
        C19B                matlab.ui.control.StateButton
        C18B                matlab.ui.control.StateButton
        C17B                matlab.ui.control.StateButton
        C16B                matlab.ui.control.StateButton
        C15B                matlab.ui.control.StateButton
        C14B                matlab.ui.control.StateButton
        C13B                matlab.ui.control.StateButton
        C12B                matlab.ui.control.StateButton
        C8B                 matlab.ui.control.StateButton
        C4B                 matlab.ui.control.StateButton
        C11B                matlab.ui.control.StateButton
        C10B                matlab.ui.control.StateButton
        C9B                 matlab.ui.control.StateButton
        C7B                 matlab.ui.control.StateButton
        C6B                 matlab.ui.control.StateButton
        C5B                 matlab.ui.control.StateButton
        C3B                 matlab.ui.control.StateButton
        C2B                 matlab.ui.control.StateButton
        C1B                 matlab.ui.control.StateButton
        ComparePlotPanel    matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        CP_all              matlab.ui.control.UIAxes
        ClusterPlotsPanel   matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        CP_20               matlab.ui.control.UIAxes
        CP_19               matlab.ui.control.UIAxes
        CP_18               matlab.ui.control.UIAxes
        CP_17               matlab.ui.control.UIAxes
        CP_2                matlab.ui.control.UIAxes
        CP_16               matlab.ui.control.UIAxes
        CP_15               matlab.ui.control.UIAxes
        CP_14               matlab.ui.control.UIAxes
        CP_13               matlab.ui.control.UIAxes
        CP_12               matlab.ui.control.UIAxes
        CP_8                matlab.ui.control.UIAxes
        CP_4                matlab.ui.control.UIAxes
        CP_11               matlab.ui.control.UIAxes
        CP_10               matlab.ui.control.UIAxes
        CP_9                matlab.ui.control.UIAxes
        CP_7                matlab.ui.control.UIAxes
        CP_6                matlab.ui.control.UIAxes
        CP_5                matlab.ui.control.UIAxes
        CP_3                matlab.ui.control.UIAxes
        CP_1                matlab.ui.control.UIAxes
        Panel               matlab.ui.container.Panel
        ExportButton        matlab.ui.control.Button
        LoadwireButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        wireFile % Description
        clusterNum
        clusterID
        allclusters
        allwaveforms
        plotColors
        newClustStValue
        newAllclusts
        allTS
        totalSecs
        wireLOC
    end
    
    methods (Access = private)
        
        function updatePLOTs(app)

            meanWave = zeros(256,app.clusterNum);
            for i = 1:app.clusterNum
                plotID = ['CP_',num2str(i)];
                clustTMP = app.clusterID(i);
                wavLocs = app.allclusters == clustTMP;
                wavTmp = transpose(app.allwaveforms(wavLocs,:));

                fRate = round(sum(wavLocs)/app.totalSecs,2);

                if size(wavTmp,2) > 50
                    randSEL = randperm(size(wavTmp,2),50);
                    wavTmp2u = wavTmp(:,randSEL);
                else
                    wavTmp2u = wavTmp;
                end

                plot(app.(plotID), wavTmp2u,'Color',app.plotColors(i,:))

                xlim(app.(plotID),[21 256])

                title(app.(plotID),['Cl ', num2str(i), ': waves = ',...
                    num2str(size(wavTmp,2)),...
                    ' | FR = ' , num2str(fRate),'Hz'],'Color',app.plotColors(i,:));

                meanWave(:,i) = mean(wavTmp2u,2);

                hold(app.CP_all,'on')
                plot(app.CP_all,meanWave(:,i),'Color',app.plotColors(i,:),...
                    'LineWidth',2.5)
                hold(app.CP_all,'off')
                xlim(app.CP_all,[1 256])

            end

            
        end
        
        function clearPLOTS(app)

            cla(app.CP_all)

            for clp = 1:20
                plotID = ['CP_',num2str(clp)];
                cla(app.(plotID))
                title(app.(plotID),[]);
            end
            
        end
        
        function resetCbuttons(app)
            
            for ci = 1:app.clusterNum
                app.(['C',num2str(ci),'B']).Value = 0;
            end

            for ci2 = 1:20
                app.(['C',num2str(ci2),'B']).Enable = 'off';
            end

            for ci3 = 1:app.clusterNum
                app.(['C',num2str(ci3),'B']).Enable = 'on';
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            allColors = [0, 18, 25;
                        0, 95, 115;
                        10, 147, 150;
                        148, 210, 189;
                        233, 216, 166;
                        238, 155, 0;
                        202, 103, 2;
                        187, 62, 3;
                        174, 32, 18;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0;
                        0 , 0, 0];

            app.plotColors = allColors/255;
        end

        % Button pushed function: LoadwireButton
        function LoadwireButtonPushed(app, event)
            
            [app.wireFile,app.wireLOC] = uigetfile();
            cd(app.wireLOC)
            load(app.wireFile)

            % Extract data
            app.clusterNum = numel(useNegative);
            app.clusterID = useNegative;
            app.allclusters = assignedNegative;
            app.allwaveforms = newSpikesNegative;
            app.allTS = newTimestampsNegative;
            TSlocs = app.allTS;
            app.totalSecs = TSlocs(end)/1000000 - TSlocs(1)/1000000;
            app.newClustStValue = 10001;

            app.ClusterSelect.Enable = "on";
            app.ClusterProcess.Enable = "on";
            for ci = 1:app.clusterNum
                app.(['C',num2str(ci),'B']).Enable = 'on';
            end

            updatePLOTs(app)

        end

        % Button pushed function: COMBINEButton
        function COMBINEButtonPushed(app, event)
            
            % Find selected clusters
            combClustInd = zeros(app.clusterNum,1,'logical');
            for ci = 1:app.clusterNum
                tmpVal = app.(['C',num2str(ci),'B']).Value;
                combClustInd(ci) = tmpVal;
            end

            if sum(combClustInd) >= 2
                combClIDs = app.clusterID(combClustInd);
                combClLocs = ismember(app.allclusters,combClIDs);
                tmpAllclusts = app.allclusters;
                tmpAllclusts(combClLocs) = app.newClustStValue;

                tmpNewCids = app.clusterID(~combClustInd);
                tmpNewCids = [tmpNewCids ; app.newClustStValue];

                app.allclusters = tmpAllclusts;
                app.newClustStValue = app.newClustStValue + 1;

                % Overwrite cluster NUM, cluster ID
                app.clusterNum = numel(tmpNewCids);
                app.clusterID = tmpNewCids;
                clearPLOTS(app)
                updatePLOTs(app)
            end

            resetCbuttons(app)

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            % Create output struct
            % Append to original file name
            clusterIDs = app.clusterID;
            clusterIND = app.allclusters;
            clusterTS = app.allTS;
            waveForms = app.allwaveforms;
            finalCheck.clustID = clusterIDs;
            finalCheck.clustIND = clusterIND;
            finalCheck.clustTS = clusterTS;
            finalCheck.waveFS = waveForms;
            finalCheck.complete = datetime;
            cd(app.wireLOC)
            save(app.wireFile,"finalCheck","-append");
            delete(app)
            

        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            
            delClustInd = zeros(app.clusterNum,1,'logical');
            for ci = 1:app.clusterNum
                tmpVal = app.(['C',num2str(ci),'B']).Value;
                delClustInd(ci) = tmpVal;
            end

            if sum(delClustInd) ~= 0
                delClIDs = app.clusterID(delClustInd);
                delClLocs = ismember(app.allclusters,delClIDs);
                tmpAllclusts = app.allclusters;
                tmpAllclusts(delClLocs) = 99999999;

                %%%% UPDATE these FOR DELETE

                tmpNewCids = app.clusterID(~delClustInd);

                app.allclusters = tmpAllclusts;

                % Overwrite cluster NUM, cluster ID
                app.clusterNum = numel(tmpNewCids);
                app.clusterID = tmpNewCids;
                clearPLOTS(app)
                updatePLOTs(app)
            end

            resetCbuttons(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LoadwireButton = uibutton(app.Panel, 'push');
            app.LoadwireButton.ButtonPushedFcn = createCallbackFcn(app, @LoadwireButtonPushed, true);
            app.LoadwireButton.Text = 'Load wire';
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ClusterPlotsPanel = uipanel(app.UIFigure);
            app.ClusterPlotsPanel.Title = 'Cluster Plots';
            app.GridLayout = uigridlayout(app.ClusterPlotsPanel);
            app.CP_1 = uiaxes(app.GridLayout);
            title(app.CP_1, 'Title')
            app.CP_1.XTick = [];
            app.CP_1.YTick = [];
            app.CP_3 = uiaxes(app.GridLayout);
            title(app.CP_3, 'Title')
            app.CP_3.XTick = [];
            app.CP_3.YTick = [];
            app.CP_5 = uiaxes(app.GridLayout);
            title(app.CP_5, 'Title')
            app.CP_5.XTick = [];
            app.CP_5.YTick = [];
            app.CP_6 = uiaxes(app.GridLayout);
            title(app.CP_6, 'Title')
            app.CP_6.XTick = [];
            app.CP_6.YTick = [];
            app.CP_7 = uiaxes(app.GridLayout);
            title(app.CP_7, 'Title')
            app.CP_7.XTick = [];
            app.CP_7.YTick = [];
            app.CP_9 = uiaxes(app.GridLayout);
            title(app.CP_9, 'Title')
            app.CP_9.XTick = [];
            app.CP_9.YTick = [];
            app.CP_10 = uiaxes(app.GridLayout);
            title(app.CP_10, 'Title')
            app.CP_10.XTick = [];
            app.CP_10.YTick = [];
            app.CP_11 = uiaxes(app.GridLayout);
            title(app.CP_11, 'Title')
            app.CP_11.XTick = [];
            app.CP_11.YTick = [];
            app.CP_4 = uiaxes(app.GridLayout);
            title(app.CP_4, 'Title')
            app.CP_4.XTick = [];
            app.CP_4.YTick = [];
            app.CP_8 = uiaxes(app.GridLayout);
            title(app.CP_8, 'Title')
            app.CP_8.XTick = [];
            app.CP_8.YTick = [];
            app.CP_12 = uiaxes(app.GridLayout);
            title(app.CP_12, 'Title')
            app.CP_12.XTick = [];
            app.CP_12.YTick = [];
            app.CP_13 = uiaxes(app.GridLayout);
            title(app.CP_13, 'Title')
            app.CP_13.XTick = [];
            app.CP_13.YTick = [];
            app.CP_14 = uiaxes(app.GridLayout);
            title(app.CP_14, 'Title')
            app.CP_14.XTick = [];
            app.CP_14.YTick = [];
            app.CP_15 = uiaxes(app.GridLayout);
            title(app.CP_15, 'Title')
            app.CP_15.XTick = [];
            app.CP_15.YTick = [];
            app.CP_16 = uiaxes(app.GridLayout);
            title(app.CP_16, 'Title')
            app.CP_16.XTick = [];
            app.CP_16.YTick = [];
            app.CP_2 = uiaxes(app.GridLayout);
            title(app.CP_2, 'Title')
            app.CP_2.XTick = [];
            app.CP_2.YTick = [];
            app.CP_17 = uiaxes(app.GridLayout);
            title(app.CP_17, 'Title')
            app.CP_17.XTick = [];
            app.CP_17.YTick = [];
            app.CP_18 = uiaxes(app.GridLayout);
            title(app.CP_18, 'Title')
            app.CP_18.XTick = [];
            app.CP_18.YTick = [];
            app.CP_19 = uiaxes(app.GridLayout);
            title(app.CP_19, 'Title')
            app.CP_19.XTick = [];
            app.CP_19.YTick = [];
            app.CP_20 = uiaxes(app.GridLayout);
            title(app.CP_20, 'Title')
            app.CP_20.XTick = [];
            app.CP_20.YTick = [];
            app.ComparePlotPanel = uipanel(app.UIFigure);
            app.ComparePlotPanel.Title = 'Compare Plot';
            app.GridLayout2 = uigridlayout(app.ComparePlotPanel);
            app.CP_all = uiaxes(app.GridLayout2);
            zlabel(app.CP_all, 'Z')
            app.CP_all.XTick = [];
            app.CP_all.YTick = [];
            app.ClusterSelect = uipanel(app.UIFigure);
            app.ClusterSelect.Enable = 'off';
            app.ClusterSelect.Title = 'Cluster Select';
            app.GridLayout3 = uigridlayout(app.ClusterSelect);
            app.C1B = uibutton(app.GridLayout3, 'state');
            app.C1B.Enable = 'off';
            app.C1B.Text = 'C1';
            app.C2B = uibutton(app.GridLayout3, 'state');
            app.C2B.Enable = 'off';
            app.C2B.Text = 'C2';
            app.C3B = uibutton(app.GridLayout3, 'state');
            app.C3B.Enable = 'off';
            app.C3B.Text = 'C3';
            app.C5B = uibutton(app.GridLayout3, 'state');
            app.C5B.Enable = 'off';
            app.C5B.Text = 'C5';
            app.C6B = uibutton(app.GridLayout3, 'state');
            app.C6B.Enable = 'off';
            app.C6B.Text = 'C6';
            app.C7B = uibutton(app.GridLayout3, 'state');
            app.C7B.Enable = 'off';
            app.C7B.Text = 'C7';
            app.C9B = uibutton(app.GridLayout3, 'state');
            app.C9B.Enable = 'off';
            app.C9B.Text = 'C9';
            app.C10B = uibutton(app.GridLayout3, 'state');
            app.C10B.Enable = 'off';
            app.C10B.Text = 'C10';
            app.C11B = uibutton(app.GridLayout3, 'state');
            app.C11B.Enable = 'off';
            app.C11B.Text = 'C11';
            app.C4B = uibutton(app.GridLayout3, 'state');
            app.C4B.Enable = 'off';
            app.C4B.Text = 'C4';
            app.C8B = uibutton(app.GridLayout3, 'state');
            app.C8B.Enable = 'off';
            app.C8B.Text = 'C8';
            app.C12B = uibutton(app.GridLayout3, 'state');
            app.C12B.Enable = 'off';
            app.C12B.Text = 'C12';
            app.C13B = uibutton(app.GridLayout3, 'state');
            app.C13B.Enable = 'off';
            app.C13B.Text = 'C13';
            app.C14B = uibutton(app.GridLayout3, 'state');
            app.C14B.Enable = 'off';
            app.C14B.Text = 'C14';
            app.C15B = uibutton(app.GridLayout3, 'state');
            app.C15B.Enable = 'off';
            app.C15B.Text = 'C15';
            app.C16B = uibutton(app.GridLayout3, 'state');
            app.C16B.Enable = 'off';
            app.C16B.Text = 'C16';
            app.C17B = uibutton(app.GridLayout3, 'state');
            app.C17B.Enable = 'off';
            app.C17B.Text = 'C17';
            app.C18B = uibutton(app.GridLayout3, 'state');
            app.C18B.Enable = 'off';
            app.C18B.Text = 'C18';
            app.C19B = uibutton(app.GridLayout3, 'state');
            app.C19B.Enable = 'off';
            app.C19B.Text = 'C19';
            app.C20B = uibutton(app.GridLayout3, 'state');
            app.C20B.Enable = 'off';
            app.C20B.Text = 'C20';
            app.SpikelocationPanel = uipanel(app.UIFigure);
            app.SpikelocationPanel.Title = 'Spike location';
            app.GridLayout4 = uigridlayout(app.SpikelocationPanel);
            app.timeSeries = uiaxes(app.GridLayout4);
            app.timeSeries.XTick = [];
            app.timeSeries.YTick = [];
            app.ClusterProcess = uipanel(app.UIFigure);
            app.ClusterProcess.Enable = 'off';
            app.ClusterProcess.Title = 'Cluster Process';
            app.GridLayout5 = uigridlayout(app.ClusterProcess);
            app.COMBINEButton = uibutton(app.GridLayout5, 'push');
            app.COMBINEButton.ButtonPushedFcn = createCallbackFcn(app, @COMBINEButtonPushed, true);
            app.COMBINEButton.Text = 'COMBINE';
            app.DELETEButton = uibutton(app.GridLayout5, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.FUTUREButton_2 = uibutton(app.GridLayout5, 'push');
            app.FUTUREButton_2.Text = 'FUTURE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OsortSpikeAssess_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end