classdef VisualTraceNLX_v07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NWBNLXViewerUIFigure     matlab.ui.Figure
        epochDUR                 matlab.ui.control.Spinner
        TimeinSecsSpinnerLabel   matlab.ui.control.Label
        threshNi                 matlab.ui.control.Spinner
        ThreshNoiseSpinnerLabel  matlab.ui.control.Label
        threshSi                 matlab.ui.control.Spinner
        ThreshSpkLabel           matlab.ui.control.Label
        minDIST                  matlab.ui.control.Spinner
        minDistSpinnerLabel      matlab.ui.control.Label
        WaveformsPanel           matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        negWave                  matlab.ui.control.UIAxes
        posWave                  matlab.ui.control.UIAxes
        allWave                  matlab.ui.control.UIAxes
        TabGroup                 matlab.ui.container.TabGroup
        ChannelViewTab           matlab.ui.container.Tab
        ChannelSelectPanel       matlab.ui.container.Panel
        C16Button                matlab.ui.control.StateButton
        C15Button                matlab.ui.control.StateButton
        C14Button                matlab.ui.control.StateButton
        C13Button                matlab.ui.control.StateButton
        C12Button                matlab.ui.control.StateButton
        C11Button                matlab.ui.control.StateButton
        C10Button                matlab.ui.control.StateButton
        C9Button                 matlab.ui.control.StateButton
        C8Button                 matlab.ui.control.StateButton
        C7Button                 matlab.ui.control.StateButton
        C6Button                 matlab.ui.control.StateButton
        C5Button                 matlab.ui.control.StateButton
        C4Button                 matlab.ui.control.StateButton
        C3Button                 matlab.ui.control.StateButton
        C2Button                 matlab.ui.control.StateButton
        C1Button                 matlab.ui.control.StateButton
        ChanSel                  matlab.ui.control.Spinner
        CHANNELLabel             matlab.ui.control.Label
        ChannelDataPanel         matlab.ui.container.Panel
        ephysGrid                matlab.ui.container.GridLayout
        AbsThrs                  matlab.ui.control.UIAxes
        Filtered                 matlab.ui.control.UIAxes
        ParametersTab            matlab.ui.container.Tab
        MainMenuPanel            matlab.ui.container.Panel
        SAVEButton               matlab.ui.control.Button
        maxEp                    matlab.ui.control.NumericEditField
        MAXEpochEditFieldLabel   matlab.ui.control.Label
        epochC                   matlab.ui.control.Spinner
        EPOCHSpinnerLabel        matlab.ui.control.Label
        Load                     matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        back                     matlab.ui.control.Button
        forward                  matlab.ui.control.Button
    end

    % TO DO
    % Determine max channel number by input matrix dimensions
    % Determine whether both sets of buttons are active.

    % Check parameters - spike

    properties (Access = private)
        loadLOC % Description
        loadFILE
        nwbFILE
        microData
        filtData
        absData
        tsData
        Fs
        procPROG
        meanALL
        sdALL
        numEpoches
        EpochInds
        locs_PGwave
        locs_NGwave
        peakP2rem
        ind2remPPks
        peakN2rem
        ind2remNPks
        PwaveForms
        NwaveForms
        waveForms
        threshSpk_P
        threshSpk_B
        threshNoise
        PwaveLocs
        NwaveLocs
        mwireCount
        miChanNid
    end

    methods (Access = private)

        function [filterData , nFs] = mmfilterFun(app , mWireData , sFs)
            % Extract relevant NWB info
            elecTYPE = 'MIcro';

            app.procPROG.Message = ['Initiating Filtering for ', elecTYPE, ' data'];
            % Sampling frequency
            % Access the struct names in the NWB acquisition field
            mwireData = mWireData;
            % Extract channel data
            % Extract vector of data
            % Extract channel of interest
            ALLchannelData = mwireData;
            filterData = zeros(size(ALLchannelData),'int16');

            % loop through channel data
            app.meanALL = zeros(size(mwireData,2),3);
            app.sdALL = zeros(size(mwireData,2),3);
            for ci = 1:size(mwireData,2)

                app.procPROG.Message = ['Filtering file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];
                tmpChannel = double(mwireData(:,ci));

                cgrDATAnew = tmpChannel;
                nFs = sFs;
                hpFreq = 600;
                lpFreq = 3000;

                app.meanALL(ci,1) = mean(cgrDATAnew);
                app.sdALL(ci,1) = std(cgrDATAnew);

                app.procPROG.Message = ['High pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];
                [highPASS,~] = highpass(cgrDATAnew , hpFreq , nFs);

                app.procPROG.Message = ['Low pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];

                [filterTmp,~] = lowpass(highPASS, lpFreq, nFs,...
                    'ImpulseResponse', 'iir', 'Steepness' ,0.8);

                filterData(:,ci) = filterTmp;
                app.meanALL(ci,2) = mean(filterTmp);
                app.sdALL(ci,2) = std(filterTmp);

                abFilt = abs(filterTmp);

                app.meanALL(ci,3) = mean(abFilt);
                app.sdALL(ci,3) = std(abFilt);

            end

        end

        function updateNLXPLOT(app)

            % add channel ID - get from spinner
            chanID = app.ChanSel.Value;
            epochID = app.epochC.Value;

            plot(app.Filtered,...
                app.filtData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
                chanID),'k')

            % Spike plot

%             plot(app.AbsThrs,...
%                 app.absData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
%                 chanID),'k')

        end

        function updateWAVEplot(app)

            sampLen = round(32000/1000);
            waveWIN = round(sampLen*2.62); % Figure out why from OSort;
            totLen = length(1 - (round(waveWIN*0.3)):1 + (round(waveWIN*0.7)-1));
            % Plot all wave
            minPeakvala = min(app.waveForms,[],'all');
            minYvala = minPeakvala + (minPeakvala * 0.1);
            maxPeakvala = max(app.waveForms,[],'all');
            maxYvala = maxPeakvala + (maxPeakvala * 0.1);

            % Plot pos wave
            minPeakvalp = min(app.PwaveForms,[],'all');
            minYvalp = minPeakvalp + (minPeakvalp * 0.1);
            maxPeakvalp = max(app.PwaveForms,[],'all');
            maxYvalp = maxPeakvalp + (maxPeakvalp * 0.1);

            % Plot neg wave
            minPeakvaln = min(app.NwaveForms,[],'all');
            minYvaln = minPeakvaln + (minPeakvaln * 0.1);
            maxPeakvaln = max(app.NwaveForms,[],'all');
            maxYvaln = maxPeakvaln + (maxPeakvaln * 0.1);

            if isempty(app.waveForms)
                cla(app.allWave)
                subtitle(app.allWave,'All = 0','Color','k');
            else
                plot(app.allWave, mean(app.PwaveForms,2), 'Color', 'r',...
                    'LineStyle','-.','LineWidth',3);
                hold(app.allWave,"on")
                plot(app.allWave, mean(app.NwaveForms,2), 'Color', 'b',...
                    'LineStyle','-.','LineWidth',3);
                hold(app.allWave,"off")
                xlim(app.allWave,[1 totLen]);
                ylim(app.allWave,[minYvala maxYvala]);
                app.allWave.XTick = [];
                app.allWave.YTick = [];
                subtitle(app.allWave,'All','Color','k');
            end

            if isempty(app.PwaveForms)
                cla(app.posWave)
                subtitle(app.posWave,'Positive = 0','Color','r');
            else
                plot(app.posWave, app.PwaveForms, 'Color', 'r');
                xlim(app.posWave,[1 totLen]);
                ylim(app.posWave,[minYvalp maxYvalp]);
                app.posWave.XTick = [];
                app.posWave.YTick = [];
                stitleStrp = ['Positive = ',num2str(size(app.PwaveForms,2))];
                subtitle(app.posWave,stitleStrp,'Color','r');
            end

            if isempty(app.NwaveForms)
                cla(app.negWave)
                subtitle(app.negWave,'Negative = 0','Color','b');
            else
                plot(app.negWave, app.NwaveForms, 'Color', 'b');
                xlim(app.negWave,[1 totLen]);
                ylim(app.negWave,[minYvaln maxYvaln]);
                app.negWave.XTick = [];
                app.negWave.YTick = [];
                stitleStrp = ['Negative = ',num2str(size(app.NwaveForms,2))];
                subtitle(app.negWave,stitleStrp,'Color','b');
            end

        end

        function calculateWaves(app)
            % Get ephys block: epochID and channID for abs
            chanID = app.ChanSel.Value;
            epochID = app.epochC.Value;
            filrawDat = double(app.filtData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
                chanID));

            sampLen = round(32000/1000); % microsecond
            minDist = sampLen * app.minDIST.Value;
            waveWIN = round(sampLen*2.62);

            waveWINinds = 1 - (round(waveWIN*0.3)):1 + (round(waveWIN*0.7)-1);
            totLen = length(waveWINinds);

            % Positive / Negative Threshold
            app.threshSpk_P = app.threshSi.Value * (median(abs(filrawDat)/0.6745));

            % Both


            % NOISE
            app.threshNoise = app.threshNi.Value * (median(abs(filrawDat)/0.6745));

            % Plot threshold line
            hold(app.Filtered, 'on')
            yline(app.Filtered, round(app.threshSpk_P),'LineWidth',2,'Color','c')
            hold(app.Filtered, 'off')

            % Positive waves
            [app.locs_PGwave ,  ~] = peakseek(filrawDat , minDist , app.threshSpk_P);

            % Negative waves
            nfilrawDat = -1*filrawDat;
            [app.locs_NGwave ,  ~] = peakseek(nfilrawDat , minDist , app.threshSpk_P);

            % Positive Noise
            [app.peakP2rem,  ~] = peakseek(nfilrawDat , minDist , app.threshNoise);

            app.ind2remPPks = ~ismember(app.locs_PGwave,app.peakP2rem);
            app.locs_PGwave = app.locs_PGwave(app.ind2remPPks);
            app.locs_PGwave = app.locs_PGwave(app.locs_PGwave > sampLen);

            % Negative Noise
            [app.peakN2rem ,  ~] = peakseek(abs(double(nfilrawDat)) , minDist , app.threshNoise);

            app.ind2remNPks = ~ismember(app.locs_NGwave,app.peakN2rem);
            app.locs_NGwave = app.locs_NGwave(app.ind2remNPks);
            app.locs_NGwave = app.locs_NGwave(app.locs_NGwave > sampLen);

            app.PwaveLocs = zeros(totLen,length(app.locs_PGwave));
            app.PwaveForms = zeros(totLen,length(app.locs_PGwave));
            for ai = 1:length(app.locs_PGwave)
                locP = app.locs_PGwave(ai);
                sampPeriod = locP - (waveWINinds);
                if any(sampPeriod > length(filrawDat))
                    app.PwaveForms(:,ai) = [];
                    app.PwaveLocs(:,ai) = [];
                    break
                elseif any(sampPeriod < 0)
                    % Get 84 points from positive number
                    posPOINTS = sampPeriod(sampPeriod > 0);
                    numOFF = 84 - length(posPOINTS);
                    maxPOINT = max(posPOINTS);
                    addPOINTs = maxPOINT+1:maxPOINT+numOFF;
                    sampPeriod = sort([addPOINTs , posPOINTS]);

                    app.PwaveForms(:,ai) = filrawDat(sampPeriod);
                    app.PwaveLocs(:,ai) = sampPeriod;
                else
                    app.PwaveForms(:,ai) = filrawDat(sampPeriod);
                    app.PwaveLocs(:,ai) = sampPeriod;
                end
            end

            app.NwaveLocs = zeros(totLen,length(app.locs_NGwave));
            app.NwaveForms = zeros(totLen,length(app.locs_NGwave));
            for ai = 1:length(app.locs_NGwave)
                locP = app.locs_NGwave(ai);
                sampPeriod = locP - (waveWINinds);
                if any(sampPeriod > length(nfilrawDat))
                    app.NwaveForms(:,ai) = [];
                    app.NwaveLocs(:,ai) = [];
                    break
                else
                    app.NwaveForms(:,ai) = nfilrawDat(sampPeriod);
                    app.NwaveLocs(:,ai) = sampPeriod;
                end
            end

            tempAllWAVES = [app.NwaveForms , app.PwaveForms];
            if size(tempAllWAVES,2) <= 100
                app.waveForms = [app.NwaveForms , app.PwaveForms];
            else
                ind2use = randperm(size(tempAllWAVES,2),100);
                app.waveForms = tempAllWAVES(:,ind2use);
            end

            % Choose to which to plot pos or neg
            % Default to positive plot
            cla(app.AbsThrs)
            xlim(app.AbsThrs,[1 length(filrawDat)])
            hold(app.AbsThrs,"on")
            for pNi = 1:width(app.PwaveLocs)
                plot(app.AbsThrs,app.PwaveLocs(:,pNi),app.PwaveForms(:,pNi),...
                    "LineWidth",0.5,"Color",'r')
            end

            for nNi = 1:width(app.NwaveLocs)
                plot(app.AbsThrs,app.NwaveLocs(:,nNi),app.NwaveForms(:,nNi),...
                    "LineWidth",0.5,"Color",'b')
            end
            hold(app.AbsThrs,"off")


        end
        
        function buttonUPDATE(app)

            numButtons = app.mwireCount;

            for ci = 1:numButtons

                app.(['C',num2str(ci),'Button']).Enable = 'on';

            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [app.loadFILE , app.loadLOC] = uigetfile('*.nwb');
            cd(app.loadLOC)
            app.procPROG = uiprogressdlg(app.NWBNLXViewerUIFigure,'Title','Loading NWB',...
                'Indeterminate','on');
            app.nwbFILE = nwbRead(app.loadFILE);

            chanNums = app.nwbFILE.general_extracellular_ephys_electrodes.vectordata.get...
                ('channID').data.load();

            wireType = cellstr(app.nwbFILE.general_extracellular_ephys_electrodes.vectordata.get...
                ('label').data.load());

            app.miChanNid = chanNums(contains(wireType,'MI'));

            close(app.procPROG)
            % Read in raw microwire
%             tmpNWBl = app.nwbFILE.acquisition.get('MicroWireSeries');
            tmpNWBl = app.nwbFILE.processing.get('ecephys').nwbdatainterface.get...
                ('LFP').electricalseries.get('MicroWireSeries');
            app.tsData = app.nwbFILE.processing.get('ecephys').nwbdatainterface.get...
                ('LFP').electricalseries.get('MicroWireSeries').timestamps.load;
            app.microData = transpose(tmpNWBl.data.load());
            app.mwireCount = size(app.microData,2);
            tmpFS = extractBetween(tmpNWBl.description,'=',':');
            app.Fs = str2double(replace(tmpFS{1},' ',''));

            % Length of Epoches = 5 seconds
            % Calculate the number of epochs

            % Duration per epoch = epochDUR
            secEpoch = app.epochDUR.Value;

            app.numEpoches = floor(height(app.microData)/(32000*secEpoch));
            app.epochC.Limits = [1 app.numEpoches];
            app.maxEp.Value = app.numEpoches;
            startINDS = transpose(1:32000*secEpoch:(32000*app.numEpoches*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; height(app.microData)];
            app.EpochInds = [startINDS , stopINDS];

            % Plot first 5 seconds of data for channel 1


            app.procPROG = uiprogressdlg(app.NWBNLXViewerUIFigure,'Title','Creating Filtered signal',...
                'Indeterminate','on');

            [app.filtData , ~] = mmfilterFun(app , app.microData , app.Fs);

            ylim(app.Filtered,[app.meanALL(1,2)-(app.sdALL(1,2)*7) app.meanALL(1,2)+(app.sdALL(1,2)*7)])

            plot(app.Filtered,app.filtData(1:(32000*secEpoch),1))

%             app.absData = abs(app.filtData);
%             plot(app.AbsThrs,app.absData(1:(32000*5),1))
%             ylim(app.AbsThrs,[app.meanALL(1,3)-(app.sdALL(1,3)*7) app.meanALL(1,3)+(app.sdALL(1,3)*7)])

            % Set xlim for all
            xlim(app.AbsThrs,[1 length(app.filtData(1:(32000*secEpoch),1))])

            close(app.procPROG)

            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)

            buttonUPDATE(app)


        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
            % Update epoch spinner

            % Do something if hit end
            if app.epochC.Value == app.numEpoches
                app.epochC.Value = app.numEpoches;
                return
            else
                app.epochC.Value = app.epochC.Value + 1;
            end

            updateNLXPLOT(app)
            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)
        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            % Do something if hit end
            if app.epochC.Value == 1
                return
            else
                app.epochC.Value = app.epochC.Value - 1;
            end

            updateNLXPLOT(app)
            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)
        end

        % Value changed function: ChanSel
        function ChanSelValueChanged(app, event)
            
            updateNLXPLOT(app)
            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)
            
        end

        % Value changed function: minDIST
        function minDISTValueChanged(app, event)

            updateNLXPLOT(app)
            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)
            
        end

        % Value changed function: threshSi
        function threshSiValueChanged(app, event)

            updateNLXPLOT(app)
            % Calculate waveform features for first block
            calculateWaves(app)
            % Plot waveforms
            updateWAVEplot(app)
            
        end

        % Value changed function: epochDUR
        function epochDURValueChanged(app, event)
            secEpoch = app.epochDUR.Value;

            app.numEpoches = floor(height(app.microData)/(32000*secEpoch));
            app.epochC.Limits = [1 app.numEpoches];
            app.maxEp.Value = app.numEpoches;
            startINDS = transpose(1:32000*secEpoch:(32000*app.numEpoches*secEpoch));
            stopINDS = [startINDS(2:end)-1 ; height(app.microData)];
            app.EpochInds = [startINDS , stopINDS];
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            
            keyboard

            % UI SELECT SAVE dir
            saveLOCATION = uigetdir();
            cd(saveLOCATION)

            % Determine buttons pressed
            logButtonIndex = zeros(1,app.mwireCount,'logical');
            for lib = 1:app.mwireCount
                tmpButton = app.(['C',num2str(lib),'Button']).Value;
                logButtonIndex(lib) = tmpButton;
            end

            % save ids of wires to process in mat file
            channStoOsort = app.miChanNid(logButtonIndex);
            sessionID = app.loadFILE;

            save('OSortChannSel.mat','channStoOsort','sessionID');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NWBNLXViewerUIFigure = uifigure('Visible', 'off');
            app.NWBNLXViewerUIFigure.Name = 'NWB NLX Viewer';
            app.MainMenuPanel = uipanel(app.NWBNLXViewerUIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.forward = uibutton(app.MainMenuPanel, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Icon = fullfile(pathToMLAPP, 'forwardButton.jpg');
            app.forward.Text = '';
            app.back = uibutton(app.MainMenuPanel, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Icon = fullfile(pathToMLAPP, 'backButton.jpg');
            app.back.Text = '';
            app.Label = uilabel(app.MainMenuPanel);
            app.Label.Text = '|';
            app.Label_2 = uilabel(app.MainMenuPanel);
            app.Label_2.Text = '|';
            app.Load = uibutton(app.MainMenuPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'L';
            app.EPOCHSpinnerLabel = uilabel(app.MainMenuPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.epochC = uispinner(app.MainMenuPanel);
            app.epochC.Limits = [1 300];
            app.epochC.Value = 1;
            app.MAXEpochEditFieldLabel = uilabel(app.MainMenuPanel);
            app.MAXEpochEditFieldLabel.Text = 'MAX Epoch';
            app.maxEp = uieditfield(app.MainMenuPanel, 'numeric');
            app.maxEp.Editable = 'off';
            app.SAVEButton = uibutton(app.MainMenuPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.TabGroup = uitabgroup(app.NWBNLXViewerUIFigure);
            app.ChannelViewTab = uitab(app.TabGroup);
            app.ChannelViewTab.Title = 'ChannelView';
            app.ChannelDataPanel = uipanel(app.ChannelViewTab);
            app.ChannelDataPanel.Title = 'Channel Data';
            app.ephysGrid = uigridlayout(app.ChannelDataPanel);
            app.Filtered = uiaxes(app.ephysGrid);
            zlabel(app.Filtered, 'Z')
            subtitle(app.Filtered, 'Filtered Data')
            app.Filtered.XTick = [];
            app.Filtered.YTick = [];
            app.AbsThrs = uiaxes(app.ephysGrid);
            zlabel(app.AbsThrs, 'Z')
            subtitle(app.AbsThrs, 'Spike Locations')
            app.AbsThrs.XTick = [];
            app.AbsThrs.YTick = [];
            app.ChannelSelectPanel = uipanel(app.ChannelViewTab);
            app.ChannelSelectPanel.Title = 'Channel Select';
            app.CHANNELLabel = uilabel(app.ChannelSelectPanel);
            app.CHANNELLabel.Text = 'CHANNEL';
            app.ChanSel = uispinner(app.ChannelSelectPanel);
            app.ChanSel.Limits = [1 16];
            app.ChanSel.ValueChangedFcn = createCallbackFcn(app, @ChanSelValueChanged, true);
            app.ChanSel.Value = 1;
            app.C1Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C1Button.Enable = 'off';
            app.C1Button.Text = 'C1';
            app.C2Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C2Button.Enable = 'off';
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C3Button.Enable = 'off';
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C4Button.Enable = 'off';
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C5Button.Enable = 'off';
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C6Button.Enable = 'off';
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C7Button.Enable = 'off';
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C8Button.Enable = 'off';
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C9Button.Enable = 'off';
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C10Button.Enable = 'off';
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C11Button.Enable = 'off';
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C12Button.Enable = 'off';
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C13Button.Enable = 'off';
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C14Button.Enable = 'off';
            app.C14Button.Text = 'C14';
            app.C15Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C15Button.Enable = 'off';
            app.C15Button.Text = 'C15';
            app.C16Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C16Button.Enable = 'off';
            app.C16Button.Text = 'C16';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.WaveformsPanel = uipanel(app.NWBNLXViewerUIFigure);
            app.WaveformsPanel.Title = 'Waveforms';
            app.GridLayout = uigridlayout(app.WaveformsPanel);
            app.allWave = uiaxes(app.GridLayout);
            subtitle(app.allWave, 'Both')
            app.allWave.XTick = [];
            app.allWave.YTick = [];
            app.posWave = uiaxes(app.GridLayout);
            subtitle(app.posWave, 'Positive')
            app.posWave.XTick = [];
            app.posWave.YTick = [];
            app.negWave = uiaxes(app.GridLayout);
            zlabel(app.negWave, 'Z')
            subtitle(app.negWave, 'Negative')
            app.negWave.XTick = [];
            app.negWave.YTick = [];
            app.minDistSpinnerLabel = uilabel(app.NWBNLXViewerUIFigure);
            app.minDistSpinnerLabel.Text = 'minDist';
            app.minDIST = uispinner(app.NWBNLXViewerUIFigure);
            app.minDIST.Step = 0.1;
            app.minDIST.Limits = [0 2.6];
            app.minDIST.ValueChangedFcn = createCallbackFcn(app, @minDISTValueChanged, true);
            app.minDIST.Value = 1.9;
            app.ThreshSpkLabel = uilabel(app.NWBNLXViewerUIFigure);
            app.ThreshSpkLabel.Text = 'ThreshSpk';
            app.threshSi = uispinner(app.NWBNLXViewerUIFigure);
            app.threshSi.Step = 0.2;
            app.threshSi.Limits = [1 8];
            app.threshSi.ValueChangedFcn = createCallbackFcn(app, @threshSiValueChanged, true);
            app.threshSi.Value = 3;
            app.ThreshNoiseSpinnerLabel = uilabel(app.NWBNLXViewerUIFigure);
            app.ThreshNoiseSpinnerLabel.Text = 'ThreshNoise';
            app.threshNi = uispinner(app.NWBNLXViewerUIFigure);
            app.threshNi.Step = 0.5;
            app.threshNi.Limits = [5 10];
            app.threshNi.Value = 8;
            app.TimeinSecsSpinnerLabel = uilabel(app.NWBNLXViewerUIFigure);
            app.TimeinSecsSpinnerLabel.Text = 'Time in Secs';
            app.epochDUR = uispinner(app.NWBNLXViewerUIFigure);
            app.epochDUR.Step = 5;
            app.epochDUR.Limits = [5 30];
            app.epochDUR.ValueChangedFcn = createCallbackFcn(app, @epochDURValueChanged, true);
            app.epochDUR.Value = 5;
            % Show the figure after all components are created
            app.NWBNLXViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualTraceNLX_v07

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NWBNLXViewerUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.NWBNLXViewerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NWBNLXViewerUIFigure)
        end
    end
end